classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotGridSwitch                 matlab.ui.control.Switch
        PlotGridSwitchLabel            matlab.ui.control.Label
        DataExportPanel                matlab.ui.container.Panel
        WriteBoundariesCheckBox        matlab.ui.control.CheckBox
        SaveDataButton                 matlab.ui.control.StateButton
        WriteNodesCheckBox             matlab.ui.control.CheckBox
        ExpertSettingsPanel            matlab.ui.container.Panel
        ResetDefaultsButton            matlab.ui.control.StateButton
        TabGroup                       matlab.ui.container.TabGroup
        NozzleSetupTab                 matlab.ui.container.Tab
        ChamberLength                  matlab.ui.control.NumericEditField
        ChamberLengthLabel             matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        DivergingRadius                matlab.ui.control.NumericEditField
        DivergingRadiusLabel           matlab.ui.control.Label
        ConvergingRadius               matlab.ui.control.NumericEditField
        ConvergingRadiusLabel          matlab.ui.control.Label
        ChamberTangentAngle            matlab.ui.control.NumericEditField
        ChamberAngleLabel              matlab.ui.control.Label
        ChamberFilletRadius            matlab.ui.control.NumericEditField
        ChamberFilletRadiusLabel       matlab.ui.control.Label
        ChamberRadius                  matlab.ui.control.NumericEditField
        ChamberRadiusLabel             matlab.ui.control.Label
        GridSizingTab                  matlab.ui.container.Tab
        NozzleN                        matlab.ui.control.NumericEditField
        NozzleLabel                    matlab.ui.control.Label
        NumberofRadialGridPointsLabel  matlab.ui.control.Label
        ChamberFilletN                 matlab.ui.control.NumericEditField
        ChamberFilletLabel             matlab.ui.control.Label
        ConvergingRadiusN              matlab.ui.control.NumericEditField
        ConvergingRadiusLabel_2        matlab.ui.control.Label
        ParabolaN                      matlab.ui.control.NumericEditField
        ParabolaLabel                  matlab.ui.control.Label
        NumberofAxialGridPointsLabel   matlab.ui.control.Label
        DivergingRadiusN               matlab.ui.control.NumericEditField
        DivergingRadiusLabel_2         matlab.ui.control.Label
        ChamberExitN                   matlab.ui.control.NumericEditField
        ChamberExitLabel               matlab.ui.control.Label
        ChamberN                       matlab.ui.control.NumericEditField
        ChamberLabel                   matlab.ui.control.Label
        InflationTab                   matlab.ui.container.Tab
        InflationInitialHeight         matlab.ui.control.NumericEditField
        InitialHeightLabel             matlab.ui.control.Label
        Inflation                      matlab.ui.control.Switch
        InflationSwitchLabel           matlab.ui.control.Label
        InflationGrowthRate            matlab.ui.control.NumericEditField
        GrowthRateLabel                matlab.ui.control.Label
        InflationN                     matlab.ui.control.NumericEditField
        NumberofLayersLabel            matlab.ui.control.Label
        NozzleGeometryPanel            matlab.ui.container.Panel
        DriverLabel                    matlab.ui.control.Label
        ExitAngle                      matlab.ui.control.NumericEditField
        ExitAngleLabel                 matlab.ui.control.Label
        NozzleLength                   matlab.ui.control.NumericEditField
        NozzleLengthEditFieldLabel     matlab.ui.control.Label
        ExpansionRatio                 matlab.ui.control.NumericEditField
        ExpansionRatioLabel            matlab.ui.control.Label
        TransitionAngle                matlab.ui.control.NumericEditField
        TransitionAngleEditFieldLabel  matlab.ui.control.Label
        ExitRadius                     matlab.ui.control.NumericEditField
        ExitRadiusEditFieldLabel       matlab.ui.control.Label
        ThroatRadius                   matlab.ui.control.NumericEditField
        ThroatRadiusEditFieldLabel     matlab.ui.control.Label
        ButtonGroup2                   matlab.ui.container.ButtonGroup
        Button_12                      matlab.ui.control.RadioButton
        Button_11                      matlab.ui.control.RadioButton
        Button_2                       matlab.ui.control.RadioButton
        ButtonGroup1                   matlab.ui.container.ButtonGroup
        Button_8                       matlab.ui.control.RadioButton
        Button_5                       matlab.ui.control.RadioButton
        Button                         matlab.ui.control.RadioButton
        Ax                             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        X % Axial coordinate of nozzle wall
        Y % Radial coordinate of nozzle wall
        f % Driving parameter matrix
        names % Nozzle property label names
    end
    
    methods (Access = private)
        
        function plotUpdate(app)
            
            [x,y] = nodes(app);
            cla(app.Ax);
            axis(app.Ax,'auto');
            
            hold(app.Ax);
            if strcmp(app.PlotGridSwitch.Value,'On') == 1
                for ii = 1:size(x,2)
                    plot(app.Ax, x(:,ii),  y(:,ii),'b')
                    plot(app.Ax, x(:,ii), -y(:,ii),'b')
                end
                
                for ii = 1:size(x,1)
                    plot(app.Ax, x(ii,:),  y(ii,:),'k')
                    plot(app.Ax, x(ii,:),  -y(ii,:),'k')
                end
                
            else
                
                plot(app.Ax, x(:,1),  y(:,1),'b')
                plot(app.Ax, x(:,1), -y(:,1),'b')
                
                plot(app.Ax, x(:,end),  y(:,end),'b')
                plot(app.Ax, x(:,end), -y(:,end),'b')
                
                plot(app.Ax, x(end,:),   y(end,:),'k')
                plot(app.Ax, x(end,:),  -y(end,:),'k')
                
                
            end
            
            axis(app.Ax,'equal');
            app.Ax.XLim = app.Ax.XLim -[app.ThroatRadius.Value, - app.ThroatRadius.Value];
            hold(app.Ax);
            
            
        end
        
        function [x, y] = converging(app)
            r_t   = app.ThroatRadius.Value;
            a_conv = app.ConvergingRadius.Value;
            
            % t_start = -135*pi/180;
            t_start = (-90 - app.ChamberTangentAngle.Value) * pi/180;
            t_end   = -90 *pi/180;
            
            t = linspace(t_start,t_end,ceil(app.ConvergingRadiusN.Value))';
            
            x = a_conv .* r_t .* cos(t);
            y = a_conv .* r_t .* (sin(t) + 1) + r_t;
            
        end
        
        function [x, y] = diverging(app)
            
            r_t   = app.ThroatRadius.Value;
            a_div = app.DivergingRadius.Value;
            
            t_start = -90*pi/180;
            t_end   = (-90 + app.TransitionAngle.Value) *pi/180;
            
            t = linspace(t_start,t_end,ceil(app.DivergingRadiusN.Value))';
            
            x = a_div .* r_t .* cos(t);
            y = a_div .* r_t .* (sin(t) + 1) + r_t;
            
        end
        
        function [x, y] = parabola(app)
            
            
            r_e   = app.ExitRadius.Value;
            t_tan = app.TransitionAngle.Value * pi/180;
            L_N   = app.NozzleLength.Value;
            
            [x, y] = diverging(app);
            
            x_n = x(end);
            y_n = y(end);
            r_n = y_n;
            
            M =  [      r_n.^2  r_n  1;
                r_e.^2 r_e   1;
                2*r_n       1  0];
            
            B = [x_n; L_N; 1/tan(t_tan)];
            
            p = mldivide(M,B);
            a = p(1);
            b = p(2);
            c = p(3);
            
            y = linspace(y_n, r_e, app.ParabolaN.Value)';
            x = a .* y.^2 + b .* y + c;
            
            app.ExitAngle.Value = atan(1./(2*a*x(end) + b))*180/pi;
            app.ExitAngle.Editable = 0;
            
            
        end
        
        function wall(app)
            
            [x_c,   y_c] = converging(app);
            [x_d,   y_d] = diverging(app);
            [x_p,   y_p] = parabola(app);
            [x_ch, y_ch] = chamber(app);
            
            app.X = [x_ch(1:end-1); x_c(1:end-1); x_d(1:end-1); x_p];
            app.Y = [y_ch(1:end-1); y_c(1:end-1); y_d(1:end-1); y_p];
            
        end
        
        function [x,y] = chamber(app)
            
            r_t = app.ThroatRadius.Value;
            
            [x_c, y_c] = converging(app);
            
            m = -tan(app.ChamberTangentAngle.Value*pi/180);
            
            c = y_c(1) - m*x_c(1);
            
            r   = app.ChamberFilletRadius.Value * r_t;
            r_c = app.ChamberRadius.Value * r_t;
            t = pi/4;
            b = r*cos(t) - (r*(sin(t) - 1) +r_c - c)/m;
            
            t = linspace((90 - app.ChamberTangentAngle.Value)*pi/180, pi/2, ceil(app.ChamberFilletN.Value))';
            %pi/4->pi/2
            x = r*cos(t)-b;
            y = r*(sin(t) - 1) +r_c;
            
            x_l = linspace(x(1), x_c(1),ceil(app.ChamberExitN.Value))';
            y_l = linspace(y(1), y_c(1),ceil(app.ChamberExitN.Value))';
            
            x = flip(x);
            y = flip(y);
            
            x_ch = linspace(x(1) - app.ChamberLength.Value*r_t, x(1), ceil(app.ChamberN.Value))';
            y_ch = r_c*ones(size(x_ch));
            
            x = [x_ch(1:end-1); x(1:end-1); x_l];
            y = [y_ch(1:end-1); y(1:end-1); y_l];
            
        end
        
        function [x_grid, y_grid] = nodes(app)
            wall(app);
            ny = ceil(app.NozzleN.Value);
            
            if strcmp(app.Inflation.Value,'On') == 1
                
                x_inf = repmat(app.X',[ceil(app.InflationN.Value),1]);
                y_inf = zeros(size(x_inf));
                
                y_inf(1,:) = app.Y';
                for ii = 2:ceil(app.InflationN.Value)
                    y_inf(ii,:) = y_inf(ii-1,:) - app.InflationInitialHeight.Value * (app.InflationGrowthRate.Value^(ii-1));
                end


                x_grid = repmat(app.X',[ny,1]);
                y_grid = zeros(size(x_grid));
                
                for ii = 1:length(app.X)
                    y_grid(:,ii) = linspace(0,y_inf(end,ii),ny)';
                end
                
                x_grid = [x_grid; x_inf(1:end-1,:)];
                y_grid = [y_grid; flip(y_inf(1:end-1,:))];
            else
                x_grid = repmat(app.X',[ny,1]);
                y_grid = zeros(size(x_grid));
                
                for ii = 1:length(app.X)
                    y_grid(:,ii) = linspace(0,app.Y(ii),ny)';
                end
            end


        end
        
        function buttonSwap(app)
            
            nButtons = length(app.ButtonGroup1.Buttons);
            props = {'ThroatRadius'; 'ExitRadius'; 'ExpansionRatio';  'NozzleLength'; 'TransitionAngle'; 'ExitAngle'};
            
            app.names = props;
            driver = zeros(nButtons,2);
            
            for ii = 1:nButtons
                driver(ii,1) = app.ButtonGroup1.Buttons(ii).Value;
                driver(ii,2) = app.ButtonGroup2.Buttons(ii).Value;
                
                name = props{ii};
                
                if sum(driver(ii,:)) == 0
                    app.(name).Editable = 0;
                else
                    app.(name).Editable = 1;
                end
            end
            
            for ii = 1:2
                for jj = 1:nButtons
                    button = sprintf('ButtonGroup%d',ii);
                    app.(button).Buttons(jj).Enable = 1;
                end
            end
            
            
            for ii = 1:2
                kk = driver(:,ii) == 1;
                for jj = 1:2
                    button = sprintf('ButtonGroup%d',jj);
                    if ii ~= jj
                        app.(button).Buttons(kk).Enable = 0;
                    end
                end
            end
            
            
            app.f = sum(driver,2);
            radius(app);
            
        end
        
        function radius(app)
            if app.f(1) == 0
                app.ThroatRadius.Value = app.ExitRadius.Value/sqrt(app.ExpansionRatio.Value);
            elseif app.f(2) == 0
                app.ExitRadius.Value = sqrt(app.ExpansionRatio.Value)*app.ThroatRadius.Value;
            else
                app.ExpansionRatio.Value = (app.ExitRadius.Value/app.ThroatRadius.Value).^2;
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            props = {'ThroatRadius'; 'ExitRadius'; 'ExpansionRatio';  'NozzleLength'; 'TransitionAngle'; 'ExitAngle'};
            app.names = props;
            plotUpdate(app);
            buttonSwap(app);
        end

        % Value changed function: ChamberExitN, ChamberFilletN, 
        % ...and 22 other components
        function ValueChanged(app, event)
            radius(app);
            plotUpdate(app);
        end

        % Callback function: ButtonGroup1, ButtonGroup2,
        % ResetDefaultsButton
        function ResetDefaults(app, event)

            app.ChamberRadius.Value = 4;
            app.ChamberLength.Value = 6;
            app.ChamberFilletRadius.Value = 1.50;
            app.ChamberTangentAngle.Value = 45;
            app.ConvergingRadius.Value = 1.50;
            app.DivergingRadius.Value = 0.382;
            
            app.ChamberN.Value = 16;
            app.ChamberFilletN.Value = 4;
            app.ChamberExitN.Value = 12;
            app.DivergingRadiusN.Value = 5;
            app.ConvergingRadiusN.Value = 8;
            app.ParabolaN.Value = 28;
            app.NozzleN.Value = 10;
            
            app.InflationN.Value = 6;
            app.InflationGrowthRate.Value = 1.2;
            app.InflationInitialHeight.Value = 0.02;
            
            app.Inflation.Value = 'On';
    
            plotUpdate(app);
            
        end

        % Value changed function: SaveDataButton
        function SaveDataButtonValueChanged(app, event)
            [XX, YY] = nodes(app);
            [filename, pathname] = uiputfile('*.csv', 'Save as');
            
            filename = strsplit(filename,'.');
            filename = filename{1};
            flag = zeros(size(XX));
            flag(:,1) = 1;
            flag(:,end) = 2;
            flag(end,:) = 3;
            flag(1,:) = 4;
            
            
            if app.WriteNodesCheckBox.Value == 1
                M = [XX(:), YY(:), flag(:)];
                nodefile = sprintf('%s%s%s_nodes.csv',pathname,filesep,filename);
                dlmwrite(nodefile,M)
            end
            
            if app.WriteBoundariesCheckBox.Value == 1
                x = [XX(:,1); XX(:,end); XX(end,:)'; XX(1,:)'];
                y = [YY(:,1); YY(:,end); YY(end,:)'; YY(1,:)'];
                ff = [flag(:,1); flag(:,end);  flag(end,:)';  flag(1,:)'];
                
                M = [x,y,ff];
                boundaryfile = sprintf('%s%s%s_boundaries.csv',pathname,filesep,filename);
                dlmwrite(boundaryfile,M)
            end
            
            fid = fopen(sprintf('%s%s%s_ID.txt', pathname, filesep, filename),'w');
            
            fprintf(fid,'BOUNDARY ID     VALUE\n');
            fprintf(fid,'--------------------\n');
            fprintf(fid,'0 - INTERIOR\n');
            fprintf(fid,'1 - INLET\n');
            fprintf(fid,'2 - OUTLET\n');
            fprintf(fid,'3 - WALL\n');
            fprintf(fid,'4 - AXIS\n');
            fclose(fid);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Ax = uiaxes(app.UIFigure);
            title(app.Ax, 'Parabolic Nozzle')
            xlabel(app.Ax, 'X')
            ylabel(app.Ax, 'Y')
            app.NozzleGeometryPanel = uipanel(app.UIFigure);
            app.NozzleGeometryPanel.Title = 'Nozzle Geometry';
            app.ButtonGroup1 = uibuttongroup(app.NozzleGeometryPanel);
            app.ButtonGroup1.SelectionChangedFcn = createCallbackFcn(app, @ResetDefaults, true);
            app.Button = uiradiobutton(app.ButtonGroup1);
            app.Button.Text = '';
            app.Button.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup1);
            app.Button_5.Text = '';
            app.Button_8 = uiradiobutton(app.ButtonGroup1);
            app.Button_8.Text = '';
            app.ButtonGroup2 = uibuttongroup(app.NozzleGeometryPanel);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ResetDefaults, true);
            app.Button_2 = uiradiobutton(app.ButtonGroup2);
            app.Button_2.Text = '';
            app.Button_11 = uiradiobutton(app.ButtonGroup2);
            app.Button_11.Text = '';
            app.Button_11.Value = true;
            app.Button_12 = uiradiobutton(app.ButtonGroup2);
            app.Button_12.Text = '';
            app.ThroatRadiusEditFieldLabel = uilabel(app.NozzleGeometryPanel);
            app.ThroatRadiusEditFieldLabel.Text = 'Throat Radius';
            app.ThroatRadius = uieditfield(app.NozzleGeometryPanel, 'numeric');
            app.ThroatRadius.LowerLimitInclusive = 'off';
            app.ThroatRadius.Limits = [0 Inf];
            app.ThroatRadius.ValueDisplayFormat = '%.2f';
            app.ThroatRadius.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ThroatRadius.Value = 1;
            app.ExitRadiusEditFieldLabel = uilabel(app.NozzleGeometryPanel);
            app.ExitRadiusEditFieldLabel.Text = 'Exit Radius';
            app.ExitRadius = uieditfield(app.NozzleGeometryPanel, 'numeric');
            app.ExitRadius.ValueDisplayFormat = '%.2f';
            app.ExitRadius.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ExitRadius.Value = 5;
            app.TransitionAngleEditFieldLabel = uilabel(app.NozzleGeometryPanel);
            app.TransitionAngleEditFieldLabel.Text = 'Transition Angle';
            app.TransitionAngle = uieditfield(app.NozzleGeometryPanel, 'numeric');
            app.TransitionAngle.LowerLimitInclusive = 'off';
            app.TransitionAngle.UpperLimitInclusive = 'off';
            app.TransitionAngle.Limits = [0 90];
            app.TransitionAngle.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.TransitionAngle.Value = 30;
            app.ExpansionRatioLabel = uilabel(app.NozzleGeometryPanel);
            app.ExpansionRatioLabel.Text = 'Expansion Ratio';
            app.ExpansionRatio = uieditfield(app.NozzleGeometryPanel, 'numeric');
            app.ExpansionRatio.LowerLimitInclusive = 'off';
            app.ExpansionRatio.Limits = [0 Inf];
            app.ExpansionRatio.ValueDisplayFormat = '%.2f';
            app.ExpansionRatio.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ExpansionRatio.Value = 12;
            app.NozzleLengthEditFieldLabel = uilabel(app.NozzleGeometryPanel);
            app.NozzleLengthEditFieldLabel.Text = 'Nozzle Length';
            app.NozzleLength = uieditfield(app.NozzleGeometryPanel, 'numeric');
            app.NozzleLength.LowerLimitInclusive = 'off';
            app.NozzleLength.Limits = [0 Inf];
            app.NozzleLength.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.NozzleLength.Value = 12;
            app.ExitAngleLabel = uilabel(app.NozzleGeometryPanel);
            app.ExitAngleLabel.Text = 'Exit Angle';
            app.ExitAngle = uieditfield(app.NozzleGeometryPanel, 'numeric');
            app.ExitAngle.LowerLimitInclusive = 'off';
            app.ExitAngle.Limits = [0 Inf];
            app.ExitAngle.ValueDisplayFormat = '%.2f';
            app.ExitAngle.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ExitAngle.Value = 12;
            app.DriverLabel = uilabel(app.NozzleGeometryPanel);
            app.DriverLabel.Text = 'Driver';
            app.ExpertSettingsPanel = uipanel(app.UIFigure);
            app.ExpertSettingsPanel.Title = 'Expert Settings';
            app.TabGroup = uitabgroup(app.ExpertSettingsPanel);
            app.NozzleSetupTab = uitab(app.TabGroup);
            app.NozzleSetupTab.Title = 'Nozzle Setup';
            app.ChamberRadiusLabel = uilabel(app.NozzleSetupTab);
            app.ChamberRadiusLabel.Text = 'Chamber Radius';
            app.ChamberRadius = uieditfield(app.NozzleSetupTab, 'numeric');
            app.ChamberRadius.LowerLimitInclusive = 'off';
            app.ChamberRadius.UpperLimitInclusive = 'off';
            app.ChamberRadius.Limits = [0 Inf];
            app.ChamberRadius.ValueDisplayFormat = '%.2f';
            app.ChamberRadius.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ChamberRadius.Value = 4;
            app.ChamberFilletRadiusLabel = uilabel(app.NozzleSetupTab);
            app.ChamberFilletRadiusLabel.Text = 'Chamber Fillet Radius';
            app.ChamberFilletRadius = uieditfield(app.NozzleSetupTab, 'numeric');
            app.ChamberFilletRadius.LowerLimitInclusive = 'off';
            app.ChamberFilletRadius.UpperLimitInclusive = 'off';
            app.ChamberFilletRadius.Limits = [0 Inf];
            app.ChamberFilletRadius.ValueDisplayFormat = '%.2f';
            app.ChamberFilletRadius.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ChamberFilletRadius.Value = 1.5;
            app.ChamberAngleLabel = uilabel(app.NozzleSetupTab);
            app.ChamberAngleLabel.Text = 'Chamber Angle';
            app.ChamberTangentAngle = uieditfield(app.NozzleSetupTab, 'numeric');
            app.ChamberTangentAngle.Limits = [0 90];
            app.ChamberTangentAngle.ValueDisplayFormat = '%.1f';
            app.ChamberTangentAngle.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ChamberTangentAngle.Value = 45;
            app.ConvergingRadiusLabel = uilabel(app.NozzleSetupTab);
            app.ConvergingRadiusLabel.Text = 'Converging Radius';
            app.ConvergingRadius = uieditfield(app.NozzleSetupTab, 'numeric');
            app.ConvergingRadius.LowerLimitInclusive = 'off';
            app.ConvergingRadius.Limits = [0 Inf];
            app.ConvergingRadius.ValueDisplayFormat = '%.2f';
            app.ConvergingRadius.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ConvergingRadius.Value = 1.5;
            app.DivergingRadiusLabel = uilabel(app.NozzleSetupTab);
            app.DivergingRadiusLabel.Text = 'Diverging Radius';
            app.DivergingRadius = uieditfield(app.NozzleSetupTab, 'numeric');
            app.DivergingRadius.LowerLimitInclusive = 'off';
            app.DivergingRadius.Limits = [0 Inf];
            app.DivergingRadius.ValueDisplayFormat = '%.2f';
            app.DivergingRadius.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.DivergingRadius.Value = 0.382;
            app.TextArea = uitextarea(app.NozzleSetupTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Lengths & radii are specified in multiples of the throat radius.'};
            app.ChamberLengthLabel = uilabel(app.NozzleSetupTab);
            app.ChamberLengthLabel.Text = 'Chamber Length';
            app.ChamberLength = uieditfield(app.NozzleSetupTab, 'numeric');
            app.ChamberLength.LowerLimitInclusive = 'off';
            app.ChamberLength.UpperLimitInclusive = 'off';
            app.ChamberLength.Limits = [1 Inf];
            app.ChamberLength.ValueDisplayFormat = '%.2f';
            app.ChamberLength.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ChamberLength.Value = 6;
            app.GridSizingTab = uitab(app.TabGroup);
            app.GridSizingTab.Title = 'Grid Sizing';
            app.ChamberLabel = uilabel(app.GridSizingTab);
            app.ChamberLabel.Text = 'Chamber';
            app.ChamberN = uieditfield(app.GridSizingTab, 'numeric');
            app.ChamberN.LowerLimitInclusive = 'off';
            app.ChamberN.UpperLimitInclusive = 'off';
            app.ChamberN.Limits = [4 Inf];
            app.ChamberN.ValueDisplayFormat = '%.0f';
            app.ChamberN.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ChamberN.Value = 16;
            app.ChamberExitLabel = uilabel(app.GridSizingTab);
            app.ChamberExitLabel.Text = 'Chamber Exit';
            app.ChamberExitN = uieditfield(app.GridSizingTab, 'numeric');
            app.ChamberExitN.LowerLimitInclusive = 'off';
            app.ChamberExitN.Limits = [3 Inf];
            app.ChamberExitN.ValueDisplayFormat = '%.0f';
            app.ChamberExitN.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ChamberExitN.Value = 12;
            app.DivergingRadiusLabel_2 = uilabel(app.GridSizingTab);
            app.DivergingRadiusLabel_2.Text = 'Diverging Radius';
            app.DivergingRadiusN = uieditfield(app.GridSizingTab, 'numeric');
            app.DivergingRadiusN.LowerLimitInclusive = 'off';
            app.DivergingRadiusN.Limits = [3 Inf];
            app.DivergingRadiusN.ValueDisplayFormat = '%.0f';
            app.DivergingRadiusN.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.DivergingRadiusN.Value = 5;
            app.NumberofAxialGridPointsLabel = uilabel(app.GridSizingTab);
            app.NumberofAxialGridPointsLabel.Text = 'Number of Axial Grid Points';
            app.ParabolaLabel = uilabel(app.GridSizingTab);
            app.ParabolaLabel.Text = 'Parabola';
            app.ParabolaN = uieditfield(app.GridSizingTab, 'numeric');
            app.ParabolaN.LowerLimitInclusive = 'off';
            app.ParabolaN.Limits = [3 Inf];
            app.ParabolaN.ValueDisplayFormat = '%.0f';
            app.ParabolaN.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ParabolaN.Value = 48;
            app.ConvergingRadiusLabel_2 = uilabel(app.GridSizingTab);
            app.ConvergingRadiusLabel_2.Text = 'Converging Radius';
            app.ConvergingRadiusN = uieditfield(app.GridSizingTab, 'numeric');
            app.ConvergingRadiusN.LowerLimitInclusive = 'off';
            app.ConvergingRadiusN.Limits = [3 Inf];
            app.ConvergingRadiusN.ValueDisplayFormat = '%.0f';
            app.ConvergingRadiusN.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ConvergingRadiusN.Value = 8;
            app.ChamberFilletLabel = uilabel(app.GridSizingTab);
            app.ChamberFilletLabel.Text = 'Chamber Fillet';
            app.ChamberFilletN = uieditfield(app.GridSizingTab, 'numeric');
            app.ChamberFilletN.LowerLimitInclusive = 'off';
            app.ChamberFilletN.Limits = [3 Inf];
            app.ChamberFilletN.ValueDisplayFormat = '%.0f';
            app.ChamberFilletN.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ChamberFilletN.Value = 4;
            app.NumberofRadialGridPointsLabel = uilabel(app.GridSizingTab);
            app.NumberofRadialGridPointsLabel.Text = 'Number of Radial Grid Points';
            app.NozzleLabel = uilabel(app.GridSizingTab);
            app.NozzleLabel.Text = 'Nozzle';
            app.NozzleN = uieditfield(app.GridSizingTab, 'numeric');
            app.NozzleN.LowerLimitInclusive = 'off';
            app.NozzleN.Limits = [5 Inf];
            app.NozzleN.ValueDisplayFormat = '%.0f';
            app.NozzleN.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.NozzleN.Value = 10;
            app.InflationTab = uitab(app.TabGroup);
            app.InflationTab.Title = 'Inflation';
            app.NumberofLayersLabel = uilabel(app.InflationTab);
            app.NumberofLayersLabel.Text = 'Number of Layers';
            app.InflationN = uieditfield(app.InflationTab, 'numeric');
            app.InflationN.LowerLimitInclusive = 'off';
            app.InflationN.Limits = [0 Inf];
            app.InflationN.ValueDisplayFormat = '%.0f';
            app.InflationN.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.InflationN.Value = 6;
            app.GrowthRateLabel = uilabel(app.InflationTab);
            app.GrowthRateLabel.Text = 'Growth Rate';
            app.InflationGrowthRate = uieditfield(app.InflationTab, 'numeric');
            app.InflationGrowthRate.LowerLimitInclusive = 'off';
            app.InflationGrowthRate.Limits = [0 Inf];
            app.InflationGrowthRate.ValueDisplayFormat = '%.2f';
            app.InflationGrowthRate.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.InflationGrowthRate.Value = 1.2;
            app.InflationSwitchLabel = uilabel(app.InflationTab);
            app.InflationSwitchLabel.Text = 'Inflation';
            app.Inflation = uiswitch(app.InflationTab, 'slider');
            app.Inflation.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Inflation.Value = 'On';
            app.InitialHeightLabel = uilabel(app.InflationTab);
            app.InitialHeightLabel.Text = 'Initial Height';
            app.InflationInitialHeight = uieditfield(app.InflationTab, 'numeric');
            app.InflationInitialHeight.LowerLimitInclusive = 'off';
            app.InflationInitialHeight.Limits = [0 Inf];
            app.InflationInitialHeight.ValueDisplayFormat = '%.2f';
            app.InflationInitialHeight.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.InflationInitialHeight.Value = 0.01;
            app.ResetDefaultsButton = uibutton(app.ExpertSettingsPanel, 'state');
            app.ResetDefaultsButton.ValueChangedFcn = createCallbackFcn(app, @ResetDefaults, true);
            app.ResetDefaultsButton.Text = 'Reset Defaults';
            app.DataExportPanel = uipanel(app.UIFigure);
            app.DataExportPanel.Title = 'Data Export';
            app.WriteNodesCheckBox = uicheckbox(app.DataExportPanel);
            app.WriteNodesCheckBox.Text = 'Write Nodes';
            app.SaveDataButton = uibutton(app.DataExportPanel, 'state');
            app.SaveDataButton.ValueChangedFcn = createCallbackFcn(app, @SaveDataButtonValueChanged, true);
            app.SaveDataButton.Text = 'Save Data';
            app.WriteBoundariesCheckBox = uicheckbox(app.DataExportPanel);
            app.WriteBoundariesCheckBox.Text = 'Write Boundaries';
            app.PlotGridSwitchLabel = uilabel(app.UIFigure);
            app.PlotGridSwitchLabel.Text = 'Plot Grid';
            app.PlotGridSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlotGridSwitch.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.PlotGridSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end